classdef LFsim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu_file                    matlab.ui.container.Menu
        Menu_file_laser              matlab.ui.container.Menu
        GF7Menu                      matlab.ui.container.Menu
        ZY303Menu                    matlab.ui.container.Menu
        Menu_file_detector           matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        Menu_3                       matlab.ui.container.Menu
        Menu_help                    matlab.ui.container.Menu
        Menu_about_us                matlab.ui.container.Menu
        Menu_code_address            matlab.ui.container.Menu
        Menu_file_mandroid           matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        Tab_footprintsim             matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes_Error                 matlab.ui.control.UIAxes
        Panel_ParaIn                 matlab.ui.container.Panel
        mJLabel                      matlab.ui.control.Label
        EditField_LaserPower         matlab.ui.control.NumericEditField
        radLabel                     matlab.ui.control.Label
        EditField_LaserAngle         matlab.ui.control.NumericEditField
        mLabel_3                     matlab.ui.control.Label
        EditField_DetectorMax        matlab.ui.control.NumericEditField
        mLabel_4                     matlab.ui.control.Label
        EditField_DetectorStep       matlab.ui.control.NumericEditField
        mLabel_5                     matlab.ui.control.Label
        EditField_LaserDis           matlab.ui.control.NumericEditField
        mLabel_6                     matlab.ui.control.Label
        EditField_DetectorMin        matlab.ui.control.NumericEditField
        Button_StartCal              matlab.ui.control.Button
        Button_StartSim              matlab.ui.control.Button
        Button_Stop                  matlab.ui.control.Button
        mLabel_7                     matlab.ui.control.Label
        EditField_PlotValue          matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        mEditFieldLabel              matlab.ui.control.Label
        mEditField_LaserDiameter     matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        nJcmEditFieldLabel           matlab.ui.control.Label
        nJcmEditField_LaserPowerAvg  matlab.ui.control.NumericEditField
        Button_LaserSim              matlab.ui.control.Button
        Button_LaserClear            matlab.ui.control.Button
        Button_CalClear              matlab.ui.control.Button
        UIAxes_Porcess               matlab.ui.control.UIAxes
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxes_LaserSim1             matlab.ui.control.UIAxes
        UIAxes_LaserSim2             matlab.ui.control.UIAxes
        UIAxes_LaserSim3             matlab.ui.control.UIAxes
        UIAxes_LaserSim4             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TMO % 总的均方差
        TMSD % 均方差误差   
        delta   % 探测器间隔
        isstopsim % 是否停止仿真
    end
    
    methods (Access = private)
        
        function errorplot(app,delta,TMO,TMSD)
            errorbar(app.UIAxes_Error,delta,TMO,TMSD,'o-','DisplayName','TMO');
            legend(app.UIAxes_Error);
        end
        
        function plotPoint(app,pointx_min,pointx_max,pointy_min,pointy_max,step)
            pointx = pointx_min:step:pointx_max;
            pointy = pointy_min:step:pointy_max;
            [X,Y] = meshgrid(pointx,pointy);
            plot(app.UIAxes_Porcess,X,Y,'k.');
        end
        
        function plotProcessCircle(app,centrex,centrey,diameter)   % 在Process里面画圆
            pos = [centrex-diameter/2,centrey-diameter/2,diameter,diameter];
            rectangle(app.UIAxes_Porcess,'Position',pos,'Curvature',[1,1]);
            axis(app.UIAxes_Porcess,'equal');
            axis(app.UIAxes_Porcess,'auto x');
        end
        
        function LaserAera = calLaserDiameter(app,value)
            % 卫星高度600km
            % value是卫星发散角，单位为角秒
            LaserAera = 600*10^3*tan(value*10^(-6)/2)*2;
            app.mEditField_LaserDiameter.Value = LaserAera;
        end
        
        function PowerAvg = updatePowerDensityAvg(app)
            LaserAera = pi*calLaserDiameter(app,app.EditField_LaserAngle.Value)^2;  % 单位为m²
            PowerAvg = app.EditField_LaserPower.Value*10^6/(LaserAera*10^4);        % 单位为nJ/cm²
        end
        
        function plotLaserPowerDensity(app,p_distribute,density_distribute,X,Y)
            mesh(app.UIAxes_LaserSim1,X,Y,p_distribute);colormap(app.UIAxes_LaserSim1,'jet');colorbar(app.UIAxes_LaserSim1);
            xlabel(app.UIAxes_LaserSim1,'x direction/m');ylabel(app.UIAxes_LaserSim1,'y direction/m');zlabel(app.UIAxes_LaserSim1,'Power Density(nJ/cm^2)');
            title(app.UIAxes_LaserSim1,'Power Density Distribute');

            contourf(app.UIAxes_LaserSim2,X,Y,p_distribute);colorbar(app.UIAxes_LaserSim2);axis(app.UIAxes_LaserSim2,'equal');
            xlabel(app.UIAxes_LaserSim2,'x direction/m');ylabel(app.UIAxes_LaserSim2,'y direction/m');zlabel(app.UIAxes_LaserSim2,'Power Density(nJ/cm^2)');
            title(app.UIAxes_LaserSim2,'Power Density Distribute');

            mesh(app.UIAxes_LaserSim3,X,Y,density_distribute);colorbar(app.UIAxes_LaserSim3);
            xlabel(app.UIAxes_LaserSim3,'x direction/m');ylabel(app.UIAxes_LaserSim3,'y direction/m');zlabel(app.UIAxes_LaserSim3,'Power Density');
            title(app.UIAxes_LaserSim3,'Gassian tansverse intensity profile');

            contourf(app.UIAxes_LaserSim4,X,Y,density_distribute);colorbar(app.UIAxes_LaserSim4);axis(app.UIAxes_LaserSim4,'equal');
            xlabel(app.UIAxes_LaserSim4,'x direction/m');ylabel(app.UIAxes_LaserSim4,'y direction/m');zlabel(app.UIAxes_LaserSim4,'Power Density');
            title(app.UIAxes_LaserSim4,'Gassian tansverse intensity profile');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 窗口大小设置
            sc_size = get(0,'ScreenSize');
            width = sc_size(3);height = sc_size(4);
            app.UIFigure.Position = [width/8,height/8,width/1.3,height/1.3];
            % 按照默认输入计算激光光斑大小
            calLaserDiameter(app,app.EditField_LaserAngle.Value);
            app.nJcmEditField_LaserPowerAvg.Value = updatePowerDensityAvg(app);
        end

        % Button pushed function: Button_StartSim
        function Button_StartSimPushed(app, event)
            diameter = app.mEditField_LaserDiameter.Value;                   % 激光光斑直径
            radium = diameter/2;
            
            delta_min= app.EditField_DetectorMin.Value;                     % 探测点之间距离的最小值
            delta_max = app.EditField_DetectorMax.Value;                    % 探测点之间距离的最大值
            step_delta = app.EditField_DetectorStep.Value;                  % 探测点之间距离的步长
            app.delta = delta_min:step_delta:delta_max;
            
            slope_step = 1;
            slope_max = 15;
            slope_angle = 0:slope_step:slope_max;
            circle_distance = app.EditField_LaserDis.Value;                 % 光斑与光斑之间的距离
            dector_x_min = 0;dector_x_max = 450;
            dector_y_min = -35;dector_y_max = 200;
            app.isstopsim = false;

            for delta_i = app.delta 
                % 每一个距离计算一次TMO和TMSD,delta_i代表当前的Δ
                % 计算得到三个圆的圆心坐标值
                centroidxy1 = calCentroid(delta_i,radium,slope_angle,circle_distance,1);
                centroidxy2 = calCentroid(delta_i,radium,slope_angle,circle_distance,2);
                centroidxy3 = calCentroid(delta_i,radium,slope_angle,circle_distance,3);
            end
                plotPoint(app,dector_x_min,dector_x_max,dector_y_min,dector_y_max,15);
                hold(app.UIAxes_Porcess,'on');
                
            for i=1:1:length(centroidxy1)
                plotProcessCircle(app,centroidxy1(1,i),centroidxy1(2,i),diameter);
                plotProcessCircle(app,centroidxy2(1,i),centroidxy2(2,i),diameter);
                plotProcessCircle(app,centroidxy3(1,i),centroidxy3(2,i),diameter);
                plot(app.UIAxes_Porcess,[centroidxy1(1,i);centroidxy3(1,i)],[centroidxy1(2,i),centroidxy3(2,i)]);
                pause(0.5);               
                if app.isstopsim == true                     
                    return;
                end                
            end
            
        end

        % Button pushed function: Button_Stop
        function Button_StopPushed(app, event)
            app.isstopsim=true;
            cla(app.UIAxes_Porcess);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isstopsim = true;
            delete(app);
            clear;          
        end

        % Button pushed function: Button_StartCal
        function Button_StartCalPushed(app, event)
            % 光斑参数
            diameter = app.mEditField_LaserDiameter.Value;                   % 激光光斑直径 
            circle_distance = app.EditField_LaserDis.Value;                  % 光斑与光斑之间的距离
            % 探测器参数
            delta_min = app.EditField_DetectorMin.Value;                     % 最小值
            step_delta = app.EditField_DetectorStep.Value;                   % 步长
            delta_max = app.EditField_DetectorMax.Value;                     % 最大值
            app.delta = delta_min:step_delta:delta_max;                      % 误差横坐标
            % 计算过程
            start_msg = uiprogressdlg(app.UIFigure,'Title','请稍后','Message','后台正在计算...','Indeterminate','on');
            tic;
            [app.TMO,app.TMSD] = simDetector(diameter,circle_distance,delta_min,step_delta,delta_max);
            elapsedtime = toc;
            close(start_msg);
            uiconfirm(app.UIFigure,['共耗时',num2str(elapsedtime),'秒'],'计算完成','Options',{'完成','关闭'},'Icon','success');
            % 绘制误差曲线
            errorplot(app,app.delta,app.TMO,app.TMSD);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            y = repmat(app.EditField_PlotValue.Value,1,length(app.delta));
            hold(app.UIAxes_Error,'on');
            plot(app.UIAxes_Error,app.delta,y,'DisplayName',['TMO=',num2str(y(1))]);
%             legend(app.UIAxes_Error);
        end

        % Value changed function: EditField_LaserAngle
        function EditField_LaserAngleValueChanged(app, event)
            value = app.EditField_LaserAngle.Value;
            calLaserDiameter(app,value);
            app.nJcmEditField_LaserPowerAvg.Value = updatePowerDensityAvg(app);
        end

        % Value changed function: EditField_LaserPower
        function EditField_LaserPowerValueChanged(app, event)
            app.nJcmEditField_LaserPowerAvg.Value = updatePowerDensityAvg(app);
        end

        % Button pushed function: Button_LaserSim
        function Button_LaserSimPushed(app, event)
            E = app.EditField_LaserPower.Value;
           [p_distribute,density_distribute,X,Y,~] = calLaserDistribute(E);
           plot_msg = uiprogressdlg(app.UIFigure,'Title','请稍后','Message','正在计算并绘制图像','Indeterminate','on');
           plotLaserPowerDensity(app,p_distribute,density_distribute,X,Y);
           close(plot_msg);
        end

        % Button pushed function: Button_LaserClear
        function Button_LaserClearPushed(app, event)
            cla(app.UIAxes_LaserSim1);
            cla(app.UIAxes_LaserSim2);
            cla(app.UIAxes_LaserSim3);
            cla(app.UIAxes_LaserSim4);
        end

        % Button pushed function: Button_CalClear
        function Button_CalClearPushed(app, event)
            cla(app.UIAxes_Error);
        end

        % Menu selected function: Menu_about_us
        function Menu_about_usSelected(app, event)
            uiconfirm(app.UIFigure,{'©安光所遥感中心'},'关于我们','Icon','info');
        end

        % Menu selected function: Menu_code_address
        function Menu_code_addressSelected(app, event)
            uiconfirm(app.UIFigure,'https://github.com/dushenda/FootprintSim','Github仓库','Icon','info');            
        end

        % Menu selected function: GF7Menu
        function GF7MenuSelected(app, event)
            app.EditField_LaserAngle.Value = 35;
            app.EditField_LaserPower.Value = 180;
            app.nJcmEditField_LaserPowerAvg.Value = updatePowerDensityAvg(app);
        end

        % Menu selected function: ZY303Menu
        function ZY303MenuSelected(app, event)
            app.EditField_LaserPower.Value = 180;
            app.EditField_LaserAngle.Value = 100;
            app.nJcmEditField_LaserPowerAvg.Value =updatePowerDensityAvg(app);
        end

        % Menu selected function: Menu_file_mandroid
        function Menu_file_mandroidSelected(app, event)
            f = uifigure('Name','文件格式说明');
            position = get(f,'InnerPosition');
             uilabel(f,'Text','待写入...','Position',[position(3)/4 position(4)/4 position(4)/4 position(4)/4]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '激光足印仿真';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu_file = uimenu(app.UIFigure);
            app.Menu_file.Text = '文件';
            app.Menu_file_laser = uimenu(app.Menu_file);
            app.Menu_file_laser.Text = '激光参数导入';
            app.GF7Menu = uimenu(app.Menu_file_laser);
            app.GF7Menu.MenuSelectedFcn = createCallbackFcn(app, @GF7MenuSelected, true);
            app.GF7Menu.Text = 'GF-7';
            app.ZY303Menu = uimenu(app.Menu_file_laser);
            app.ZY303Menu.MenuSelectedFcn = createCallbackFcn(app, @ZY303MenuSelected, true);
            app.ZY303Menu.Text = 'ZY-3(03)';
            app.Menu_file_detector = uimenu(app.Menu_file);
            app.Menu_file_detector.Text = '探测器参数导入';
            app.Menu = uimenu(app.Menu_file_detector);
            app.Menu.Text = '模式1';
            app.Menu_2 = uimenu(app.Menu_file_detector);
            app.Menu_2.Text = '模式2';
            app.Menu_3 = uimenu(app.Menu_file_detector);
            app.Menu_3.Text = '模式3';
            app.Menu_help = uimenu(app.UIFigure);
            app.Menu_help.Text = '帮助';
            app.Menu_about_us = uimenu(app.Menu_help);
            app.Menu_about_us.MenuSelectedFcn = createCallbackFcn(app, @Menu_about_usSelected, true);
            app.Menu_about_us.Text = '关于';
            app.Menu_code_address = uimenu(app.Menu_help);
            app.Menu_code_address.MenuSelectedFcn = createCallbackFcn(app, @Menu_code_addressSelected, true);
            app.Menu_code_address.Text = '开源地址';
            app.Menu_file_mandroid = uimenu(app.Menu_help);
            app.Menu_file_mandroid.MenuSelectedFcn = createCallbackFcn(app, @Menu_file_mandroidSelected, true);
            app.Menu_file_mandroid.Text = '文件格式说明';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_footprintsim = uitab(app.TabGroup);
            app.Tab_footprintsim.Title = '仿真';
            app.GridLayout2 = uigridlayout(app.Tab_footprintsim);
            app.UIAxes_Error = uiaxes(app.GridLayout2);
            title(app.UIAxes_Error, '误差曲线')
            xlabel(app.UIAxes_Error, 'Array Detector Spacing(m)')
            ylabel(app.UIAxes_Error, 'Total Mean Offset(m)')
            app.Panel_ParaIn = uipanel(app.GridLayout2);
            app.Panel_ParaIn.Title = '参数配置';
            app.mJLabel = uilabel(app.Panel_ParaIn);
            app.mJLabel.Text = '激光单脉冲能量(mJ)';
            app.EditField_LaserPower = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserPower.Limits = [0 Inf];
            app.EditField_LaserPower.ValueChangedFcn = createCallbackFcn(app, @EditField_LaserPowerValueChanged, true);
            app.EditField_LaserPower.Value = 180;
            app.radLabel = uilabel(app.Panel_ParaIn);
            app.radLabel.Text = '发散角(μrad)';
            app.EditField_LaserAngle = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserAngle.Limits = [0 Inf];
            app.EditField_LaserAngle.ValueChangedFcn = createCallbackFcn(app, @EditField_LaserAngleValueChanged, true);
            app.EditField_LaserAngle.Value = 100;
            app.mLabel_3 = uilabel(app.Panel_ParaIn);
            app.mLabel_3.Text = '探测器最大间距(m)';
            app.EditField_DetectorMax = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorMax.Limits = [20 50];
            app.EditField_DetectorMax.Value = 35;
            app.mLabel_4 = uilabel(app.Panel_ParaIn);
            app.mLabel_4.Text = '探测器间距步长(m)';
            app.EditField_DetectorStep = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorStep.Limits = [1 2];
            app.EditField_DetectorStep.Value = 1;
            app.mLabel_5 = uilabel(app.Panel_ParaIn);
            app.mLabel_5.Text = '光斑与光斑距离(m)';
            app.EditField_LaserDis = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserDis.Limits = [0 Inf];
            app.EditField_LaserDis.Value = 170;
            app.mLabel_6 = uilabel(app.Panel_ParaIn);
            app.mLabel_6.Text = '探测器最小间距(m)';
            app.EditField_DetectorMin = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorMin.Limits = [1 20];
            app.EditField_DetectorMin.RoundFractionalValues = 'on';
            app.EditField_DetectorMin.Value = 1;
            app.Button_StartCal = uibutton(app.Panel_ParaIn, 'push');
            app.Button_StartCal.ButtonPushedFcn = createCallbackFcn(app, @Button_StartCalPushed, true);
            app.Button_StartCal.Text = '开始计算';
            app.Button_StartSim = uibutton(app.Panel_ParaIn, 'push');
            app.Button_StartSim.ButtonPushedFcn = createCallbackFcn(app, @Button_StartSimPushed, true);
            app.Button_StartSim.Text = '开始自动仿真';
            app.Button_Stop = uibutton(app.Panel_ParaIn, 'push');
            app.Button_Stop.ButtonPushedFcn = createCallbackFcn(app, @Button_StopPushed, true);
            app.Button_Stop.Text = '停止自动仿真';
            app.mLabel_7 = uilabel(app.Panel_ParaIn);
            app.mLabel_7.Text = '设置误差值(m)';
            app.EditField_PlotValue = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_PlotValue.Value = 4.5;
            app.PlotButton = uibutton(app.Panel_ParaIn, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.mEditFieldLabel = uilabel(app.Panel_ParaIn);
            app.mEditFieldLabel.Text = '激光光斑直径(m)';
            app.mEditField_LaserDiameter = uieditfield(app.Panel_ParaIn, 'numeric');
            app.mEditField_LaserDiameter.Editable = 'off';
            app.Label_6 = uilabel(app.Panel_ParaIn);
            app.Label_6.Text = '输出参数:';
            app.Label_7 = uilabel(app.Panel_ParaIn);
            app.Label_7.Text = '输入参数:';
            app.Label_8 = uilabel(app.Panel_ParaIn);
            app.Label_8.Text = '功能按钮:';
            app.Label_9 = uilabel(app.Panel_ParaIn);
            app.Label_9.Text = '辅助后处理:';
            app.nJcmEditFieldLabel = uilabel(app.Panel_ParaIn);
            app.nJcmEditFieldLabel.Text = '平均能量密度(nJ/cm²)';
            app.nJcmEditField_LaserPowerAvg = uieditfield(app.Panel_ParaIn, 'numeric');
            app.nJcmEditField_LaserPowerAvg.Editable = 'off';
            app.Button_LaserSim = uibutton(app.Panel_ParaIn, 'push');
            app.Button_LaserSim.ButtonPushedFcn = createCallbackFcn(app, @Button_LaserSimPushed, true);
            app.Button_LaserSim.Text = '激光能量密度';
            app.Button_LaserClear = uibutton(app.Panel_ParaIn, 'push');
            app.Button_LaserClear.ButtonPushedFcn = createCallbackFcn(app, @Button_LaserClearPushed, true);
            app.Button_LaserClear.Text = '清空重画';
            app.Button_CalClear = uibutton(app.Panel_ParaIn, 'push');
            app.Button_CalClear.ButtonPushedFcn = createCallbackFcn(app, @Button_CalClearPushed, true);
            app.Button_CalClear.Text = '清空重算';
            app.UIAxes_Porcess = uiaxes(app.GridLayout2);
            title(app.UIAxes_Porcess, '仿真过程')
            xlabel(app.UIAxes_Porcess, 'X Direction')
            ylabel(app.UIAxes_Porcess, 'Y Direction')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UIAxes_LaserSim1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_LaserSim1, 'Plot1')
            xlabel(app.UIAxes_LaserSim1, 'X')
            ylabel(app.UIAxes_LaserSim1, 'Y')
            app.UIAxes_LaserSim2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_LaserSim2, 'Plot2')
            xlabel(app.UIAxes_LaserSim2, 'X')
            ylabel(app.UIAxes_LaserSim2, 'Y')
            app.UIAxes_LaserSim3 = uiaxes(app.GridLayout3);
            title(app.UIAxes_LaserSim3, 'Plot3')
            xlabel(app.UIAxes_LaserSim3, 'X')
            ylabel(app.UIAxes_LaserSim3, 'Y')
            app.UIAxes_LaserSim4 = uiaxes(app.GridLayout3);
            title(app.UIAxes_LaserSim4, 'Plot4')
            xlabel(app.UIAxes_LaserSim4, 'X')
            ylabel(app.UIAxes_LaserSim4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LFsim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end