% description: ©中国科学院安徽光学精密机械研究所
classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Menu        matlab.ui.container.Menu
        Menu_3      matlab.ui.container.Menu
        Menu_4      matlab.ui.container.Menu
        Menu_11     matlab.ui.container.Menu
        Menu_12     matlab.ui.container.Menu
        Menu_5      matlab.ui.container.Menu
        Menu_6      matlab.ui.container.Menu
        Menu_7      matlab.ui.container.Menu
        Menu_16     matlab.ui.container.Menu
        Menu_17     matlab.ui.container.Menu
        Menu_18     matlab.ui.container.Menu
        Menu_13     matlab.ui.container.Menu
        Menu_14     matlab.ui.container.Menu
        Menu_15     matlab.ui.container.Menu
        Menu_8      matlab.ui.container.Menu
        Menu_9      matlab.ui.container.Menu
        Menu_10     matlab.ui.container.Menu
        GridLayout  matlab.ui.container.GridLayout
        Image       matlab.ui.control.Image
    end

    
    properties (Access = private)
        LFSimPop % Description
        serialPort % Description
        DataProcess % Description
        fpUse % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            % 打开串口窗口
            app.serialPort = SerialsCommunicationGUI();
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            % 启动仿真
            app.LFSimPop = LFsim(app);
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            % 退出仿真界面
            delete(app.LFSimPop);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            % 退出程序
            delete(app);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            % 关闭串口
            delete(app.serialPort);
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            % 打开数据处理系统
            app.DataProcess = processData(app);
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            % 关闭数据处理系统
            delete(app.DataProcess);
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            uiconfirm(app.UIFigure,'©2019 中国科学院安徽光学精密机械研究所','关于我们',"Icon",'info');
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            web('https://dushenda.github.io/eBook1/','-browser');
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            app.fpUse = ProFP(app);
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            delete(app.fpUse);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '卫星激光测高探测器分布仿真计算软件';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '退出';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '仿真';
            app.Menu_11 = uimenu(app.Menu_4);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '启动仿真';
            app.Menu_12 = uimenu(app.Menu_4);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '关闭仿真';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '数据解析';
            app.Menu_6 = uimenu(app.Menu_5);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '打开数据接收解析器';
            app.Menu_7 = uimenu(app.Menu_5);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '关闭数据接收解析器';
            app.Menu_16 = uimenu(app.UIFigure);
            app.Menu_16.Text = '足印接收';
            app.Menu_17 = uimenu(app.Menu_16);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '打开';
            app.Menu_18 = uimenu(app.Menu_16);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '关闭';
            app.Menu_13 = uimenu(app.UIFigure);
            app.Menu_13.Text = '数据处理';
            app.Menu_14 = uimenu(app.Menu_13);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '打开处理系统';
            app.Menu_15 = uimenu(app.Menu_13);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '关闭处理系统';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '帮助';
            app.Menu_9 = uimenu(app.Menu_8);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '关于我们';
            app.Menu_10 = uimenu(app.Menu_8);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '使用指南';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'rocket-1245696_1920.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end