classdef ProFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Panel_3            matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        Panel_2            matlab.ui.container.Panel
        Panel_4            matlab.ui.container.Panel
        GridLayout4        matlab.ui.container.GridLayout
        UIAxes             matlab.ui.control.UIAxes
        GridLayout3        matlab.ui.container.GridLayout
        Panel_5            matlab.ui.container.Panel
        Lamp               matlab.ui.control.Lamp
        Label              matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        DropDownLabel      matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Switch             matlab.ui.control.Switch
        Button_2           matlab.ui.control.Button
        XEditFieldLabel    matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        YEditFieldLabel    matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        EditFieldLabel     matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        Panel_6            matlab.ui.container.Panel
        Label_3            matlab.ui.control.Label
        EditField_2        matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        EditField_3        matlab.ui.control.EditField
        Label_5            matlab.ui.control.Label
        EditField_4        matlab.ui.control.EditField
        Label_6            matlab.ui.control.Label
        EditField_5        matlab.ui.control.EditField
        Label_7            matlab.ui.control.Label
        EditField_6        matlab.ui.control.EditField
        Label_8            matlab.ui.control.Label
        EditField_7        matlab.ui.control.EditField
        UTCEditFieldLabel  matlab.ui.control.Label
        UTCEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
        SerialPort % Description
        Count % Description
        matrix % Description
        MaxPower % Description
        MaxPowerPos % Description
        Address % Description
        Lat % Description
        Lon % Description
        TriggerTime % Description
        MyTime % Description
        
        X % Description
        Y % Description
    end
    
    methods (Access = private)
        
        function [] = getSerialData(app,obj,~)
            str = fgetl(obj);
%             oldData = get(app.UITable,'Data');
%             % 正则表达式分割数组
            strCell = regexp(str,',','split');
%             Address = getAddressCode(strCell{2});
%             [PositionLatStr,~] = getLatitude(strCell{3});
%             [PositionLonStr,~] = getLongitude(strCell{4});
%             myTime = datestr(getTime(strCell{5}));
%             TriggerTime = getTriggerTime(strCell{6});
            Power = getPower(strCell(7));

%             % 添加数组数据进表格
%             newRow = {app.Count,Address,PositionLatStr,PositionLonStr,myTime,TriggerTime,Power};
%             newData = [oldData; newRow];
%             % 输入数据进行排序
%             newData = sortrows(newData,2,'descend');
%             set(app.UITable,'Data',newData);
            [row,col] = app.getPos(6);
            app.matrix(row,col) = Power;
            if Power>app.MaxPower
                % 获取最大值的信息
                app.MaxPower = Power;
                app.MaxPowerPos = [row,col];
                app.Address = getAddressCode(strCell{2});
                [app.Lat,~] = getLatitude(strCell{3});
                [app.Lon,~] = getLongitude(strCell{4});
                app.MyTime = datestr(getTime(strCell{5}));
                app.TriggerTime = getTriggerTime(strCell{6});
            end
            heatmap(app.Panel_2,app.matrix);
            surf(app.UIAxes,app.matrix);
            app.Count=app.Count+1;
        end
        
        function [] = closeSerial(app)
            try
                scom = instrfind;
                fclose(scom);
                delete(scom); 
            catch
                uiconfirm(app.UIFigure,'串口关闭失败',"错误",'Icon','error');
            end
        end
        
        function [] = openSerial(app,s)
            try                                             % 异常捕捉
                fopen(s);
            catch
                uiconfirm(app.UIFigure,'串口创建失败',"错误",'Icon','error');
                app.Switch.Value = 'Off';
                app.Lamp.Color='r';
                app.Label_2.Text = '串口未打开';
            end
        end
        
        function [row,col] = getPos(app,line)
            num = app.Count;
            row = ceil(num/line);
            col = mod(num,line);
            if col==0
                col=6;
            end
        end
        
        function [xo,yo] = calCenter(app)
            
            xo = sum(app.X.*app.matrix,"all")/sum(app.matrix,'all');
            yo = sum(app.Y.*app.matrix,"all")/sum(app.matrix,'all');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            x=1:6;
            y=1:6;
            [app.X,app.Y] = meshgrid(x,y);
            app.matrix = zeros(6);
            app.MaxPower = 0;
            app.MaxPowerPos = [1,1];
            app.Count = 1;
            app.Panel_2.AutoResizeChildren='off';
            app.Panel_3.AutoResizeChildren = 'off';
            app.Image.ImageSource = './LFSim_resources/webwxgetmsgimg.jpg';
            %% Test
            heatmap(app.Panel_2,app.matrix);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                app.Lamp.Color='r';
                app.Label_2.Text = '串口未打开';
                app.closeSerial();
            else
                % 显示信息
                app.Lamp.Color = 'g';
                app.Label_2.Text = [app.DropDown.Value,'已打开'];
                % 串口接收设置
                scom = instrfind;
                if ~isempty(scom)
                    fclose(scom);
                    delete(scom); 
                end
                
                app.SerialPort = serial(app.DropDown.Value);
                set(app.SerialPort,'BaudRate',app.EditField.Value);
                set(app.SerialPort,'BytesAvailableFcnCount',69);
                set(app.SerialPort,'BytesAvailableFcnMode','byte');
                set(app.SerialPort,'BytesAvailableFcn',{@app.getSerialData});
                app.openSerial(app.SerialPort);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            stringArray = seriallist;
            if isempty(stringArray)
                stringArray = '无串口连接';
            end
            uiconfirm(app.UIFigure,stringArray,'硬件已连串口','Icon','info');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.EditField_2.Value = [num2str(app.MaxPowerPos(1)),',',num2str(app.MaxPowerPos(2))];% 编号
            app.EditField_3.Value = app.Address;% 组号
            app.EditField_4.Value = app.Lon; %经度
            app.EditField_5.Value = app.Lat; % 纬度
            app.EditField_6.Value = num2str(app.MaxPower); % 能量
            app.EditField_7.Value = app.TriggerTime; %触发时刻
            app.UTCEditField.Value = app.MyTime; % UTC时间
            [xo,yo] = app.calCenter();
            app.XEditField.Value = xo;
            app.YEditField.Value = yo;
            disp(app.matrix);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.Image = uiimage(app.GridLayout2);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '接收细节';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '数据展示';
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel_5 = uipanel(app.GridLayout3);
            app.Panel_5.Title = '串口';
            app.Lamp = uilamp(app.Panel_5);
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '串口状态:';
            app.Label_2 = uilabel(app.Panel_5);
            app.Label_2.Text = '串口未打开';
            app.Button = uibutton(app.Panel_5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '查看硬件连接串口';
            app.DropDownLabel = uilabel(app.Panel_5);
            app.DropDownLabel.Text = '选择串口';
            app.DropDown = uidropdown(app.Panel_5);
            app.DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropDown.Value = 'COM1';
            app.Switch = uiswitch(app.Panel_5, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Button_2 = uibutton(app.Panel_5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '传输完成';
            app.XEditFieldLabel = uilabel(app.Panel_5);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel_5, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel_5);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel_5, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel_5);
            app.EditFieldLabel.Text = '波特率';
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField.Value = 9600;
            app.Panel_6 = uipanel(app.GridLayout3);
            app.Panel_6.Title = '最值';
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = '编号';
            app.EditField_2 = uieditfield(app.Panel_6, 'text');
            app.EditField_2.Editable = 'off';
            app.Label_4 = uilabel(app.Panel_6);
            app.Label_4.Text = '组号';
            app.EditField_3 = uieditfield(app.Panel_6, 'text');
            app.EditField_3.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_6);
            app.Label_5.Text = '经度';
            app.EditField_4 = uieditfield(app.Panel_6, 'text');
            app.EditField_4.Editable = 'off';
            app.Label_6 = uilabel(app.Panel_6);
            app.Label_6.Text = '纬度';
            app.EditField_5 = uieditfield(app.Panel_6, 'text');
            app.EditField_5.Editable = 'off';
            app.Label_7 = uilabel(app.Panel_6);
            app.Label_7.Text = '能量';
            app.EditField_6 = uieditfield(app.Panel_6, 'text');
            app.EditField_6.Editable = 'off';
            app.Label_8 = uilabel(app.Panel_6);
            app.Label_8.Text = '触发时刻';
            app.EditField_7 = uieditfield(app.Panel_6, 'text');
            app.EditField_7.Editable = 'off';
            app.UTCEditFieldLabel = uilabel(app.Panel_6);
            app.UTCEditFieldLabel.Text = 'UTC时间';
            app.UTCEditField = uieditfield(app.Panel_6, 'text');
            app.UTCEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProFP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end