classdef processData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Panel       matlab.ui.container.Panel
        Button      matlab.ui.control.Button
        Panel_2     matlab.ui.container.Panel
        Panel_3     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        CallingApp % Description
        gx1 % Description
        gx2 % Description
        fitFile % Description
        fitPath % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 导入地理数据集
            [file,path] = uigetfile('*.csv');
            if(file~=0)
                [lat,lon,pow] = plotGeoData(file,path);
                delete(app.gx1);
                delete(app.gx2);
                app.gx1 = geoaxes(app.Panel_2,"Basemap","landcover");
                app.gx2 = geoaxes(app.Panel_3,"Basemap","landcover");  
                geoscatter(app.gx1,lat,lon);            
                geodensityplot(app.gx2,lat,lon,pow);
            end
            
        end

        % Callback function
        function Button_2Pushed(app, event)
            % 导入探测器数据
            [app.fitFile,app.fitPath] = uigetfile({'*.xlsx';'*.csv';'*.xls'});            
        end

        % Callback function
        function Button_3Pushed(app, event)
            % 拟合结果
            if(app.fitFile~=0)
                [fitresult,gof,~, ~, ~] = fitGauss(app.fitFile,app.fitPath,app.EditField.Value);
                app.sseEditField.Value = gof.sse;
                app.rsquareEditField.Value = gof.rsquare;
                app.dfeEditField.Value = gof.dfe;
                app.adjrsquareEditField.Value = gof.adjrsquare;
                app.rmseEditField.Value = gof.rmse;
                app.TextArea.Value = fitresult;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数据处理';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '控制器';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入地理数据集';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '数据点分布';
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '能量分布';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end